classdef SyS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        DirectorioFoder            matlab.ui.control.EditField
        DestinationDir             matlab.ui.control.Button
        OutpuFormatText            matlab.ui.control.Label
        OutputNameText             matlab.ui.control.Label
        OutputNameField            matlab.ui.control.EditField
        ExportText                 matlab.ui.control.Label
        OutpuFormat                matlab.ui.control.DropDown
        ExportarButton             matlab.ui.control.Button
        FrecuenciaEditField        matlab.ui.control.EditField
        FrecuenciaEditFieldLabel   matlab.ui.control.Label
        GraficarButton             matlab.ui.control.Button
        CheckBox2                  matlab.ui.control.CheckBox
        Grid                       matlab.ui.control.CheckBox
        DirectorioEditField        matlab.ui.control.EditField
        DirectorioEditFieldLabel   matlab.ui.control.Label
        DuracionEditField          matlab.ui.control.EditField
        DuracionEditFieldLabel     matlab.ui.control.Label
        CreadoelEditField          matlab.ui.control.EditField
        CreadoelEditFieldLabel     matlab.ui.control.Label
        TamaoKBEditField           matlab.ui.control.EditField
        TamaoKBEditFieldLabel      matlab.ui.control.Label
        NombreEditField            matlab.ui.control.EditField
        NombreEditFieldLabel       matlab.ui.control.Label
        ListBox                    matlab.ui.control.ListBox
        Label                      matlab.ui.control.Label
        SelecioneunamuestraButton  matlab.ui.control.Button
        fileStatus                 matlab.ui.control.Label
        SignalFftFiltered          matlab.ui.control.UIAxes
        SignalFft                  matlab.ui.control.UIAxes
        SignalFiltered             matlab.ui.control.UIAxes
        Signal                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelecioneunamuestraButton
        function SelecioneunamuestraButtonPushed(app, event)
            global file path 
            [file, path] = uigetfile('*.mat', 'Seleccione un archivo name.mat');
            
            if isequal(file, 0)
                % Crear un texto y editarlo para el usuario %
                app.fileStatus.Text = 'No se pudo abrir';
                
                % COlocar logica para vaciar los campos %
                
                return;
            else 
                %app.fileStatus.Text = fullfile(path, file);
                %disp(fullfile(path, file));
                %data = loadMatFile(fullfile(path, file));
                %disp(data);
                app.DirectorioEditField.Value = fullfile(path, file);
                
                % Llama a la función loadMatFileProperties
                fileInfo = loadMatFileProperties(fullfile(path, file));
        
                % Muestra la información del archivo en la interfaz
                infoText = '';
                
                 % Añadir duraciones de las variables si existen
                variableNames = fieldnames(fileInfo.duration);
                for i = 1:length(variableNames)
                    varName = variableNames{i};
                    durationText = sprintf('%s: %.2f s\n', varName, fileInfo.duration.(varName));
                    app.DuracionEditField.Value = strcat(infoText, durationText);
                end
                app.CreadoelEditField.Value =  fileInfo.date;
                app.NombreEditField.Value = fileInfo.name;
                app.TamaoKBEditField.Value = sprintf('%s', fileInfo.size / 1024);
                app.FrecuenciaEditField.Value = '500 Hz';
                
                return;
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
%             value = app.ListBox.Value;
%             
%             cla(app.Signal);
%             cla(app.SignalFiltered);
%             cla(app.SignalFft);
%             cla(app.SignalFftFiltered);
%             
%             switch value
%                 case 'Entradas'
%                    % Mostrar EditFields y Labels asociados
%                    app.CreadoelEditField.Visible = 'on';
%                    app.CreadoelEditFieldLabel.Visible = 'on';
%                    app.DirectorioEditField.Visible = 'on';
%                    app.DirectorioEditFieldLabel.Visible = 'on';
%                    app.NombreEditField.Visible = 'on';
%                    app.NombreEditFieldLabel.Visible = 'on';    
%                    app.fileStatus.Visible = 'on';    
%                    app.TamaoKBEditField.Visible = 'on';
%                    app.TamaoKBEditFieldLabel.Visible = 'on';         
%                    app.DuracionEditField.Visible = 'on';
%                    app.DuracionEditFieldLabel.Visible = 'on';      
%                    app.SelecioneunamuestraButton.Visible = 'on';
%                    app.FrecuenciaEditField.Visible = 'on';
%                    app.FrecuenciaEditFieldLabel.Visible = 'on';
%                    
%                    app.Signal.Visible = 'off';
%                    app.SignalFiltered.Visible = 'off';
%                    app.SignalFft.Visible = 'off';
%                    app.SignalFftFiltered.Visible = 'off';
%                    app.GraficarButton.Visible = 'off';
%                    app.Grid.Visible = 'off';
%                    
%                    app.Signal.Toolbar.Visible = 'off';
%                    app.SignalFiltered.Toolbar.Visible  = 'off';
%                    app.SignalFft.Toolbar.Visible  = 'off';
%                    app.SignalFftFiltered.Toolbar.Visible  = 'off';
%                    
%                    app.ExportText.Visible = 'off';
%                    app.OutpuFormat.Visible = 'off';
%                    app.ExportarButton.Visible = 'off';
%                    app.OutputNameField.Visible = 'off';
%                    app.OutpuFormatText.Visible = 'off';
%                    app.OutputNameText.Visible = 'off';
%                    
%                    return;  
%                    
%                    
%                 case 'Visualizar'
%                    app.CreadoelEditField.Visible = 'off';
%                    app.CreadoelEditFieldLabel.Visible = 'off';   
%                    app.DirectorioEditField.Visible = 'off';
%                    app.DirectorioEditFieldLabel.Visible = 'off';            
%                    app.NombreEditField.Visible = 'off';
%                    app.NombreEditFieldLabel.Visible = 'off';        
%                    app.fileStatus.Visible = 'off';           
%                    app.TamaoKBEditField.Visible = 'off';
%                    app.TamaoKBEditFieldLabel.Visible = 'off';            
%                    app.DuracionEditField.Visible = 'off';
%                    app.DuracionEditFieldLabel.Visible = 'off';           
%                    app.SelecioneunamuestraButton.Visible = 'off';
%                    app.FrecuenciaEditField.Visible = 'off';
%                    app.FrecuenciaEditFieldLabel.Visible = 'off';
%                    
%                    app.ExportText.Visible = 'off';
%                    app.OutpuFormat.Visible = 'off';
%                    app.ExportarButton.Visible = 'off';
%                    app.OutputNameField.Visible = 'off';
%                    app.OutpuFormatText.Visible = 'off';
%                    app.OutputNameText.Visible = 'off';
%                    
%                    
%                    app.Signal.Visible = 'on';
%                    app.SignalFiltered.Visible = 'on';
%                    app.SignalFft.Visible = 'on';
%                    app.SignalFftFiltered.Visible = 'on';
%                    
%                    app.GraficarButton.Visible = 'on';
%                    app.Grid.Visible = 'on';
%                    
%                    app.Signal.Toolbar.Visible = 'on';
%                    app.SignalFiltered.Toolbar.Visible  = 'on';
%                    app.SignalFft.Toolbar.Visible  = 'on';
%                    app.SignalFftFiltered.Toolbar.Visible  = 'on';
%                    
%                    return;
%                     
%                 case 'Exportar'
%                    app.CreadoelEditField.Visible = 'off';
%                    app.CreadoelEditFieldLabel.Visible = 'off';
%                    app.DirectorioEditField.Visible = 'off';
%                    app.DirectorioEditFieldLabel.Visible = 'off';            
%                    app.NombreEditField.Visible = 'off';
%                    app.NombreEditFieldLabel.Visible = 'off';            
%                    app.fileStatus.Visible = 'off';          
%                    app.TamaoKBEditField.Visible = 'off';
%                    app.TamaoKBEditFieldLabel.Visible = 'off';        
%                    app.DuracionEditField.Visible = 'off';
%                    app.DuracionEditFieldLabel.Visible = 'off';           
%                    app.SelecioneunamuestraButton.Visible = 'off';
%                    app.FrecuenciaEditField.Visible = 'off';
%                    app.FrecuenciaEditFieldLabel.Visible = 'off';
%                    
%                    app.Signal.Visible = 'off';
%                    app.SignalFiltered.Visible = 'off';
%                    app.SignalFft.Visible = 'off';
%                    app.SignalFftFiltered.Visible = 'off';
%                    app.GraficarButton.Visible = 'off';
%                    app.Grid.Visible = 'off';
%                    app.Signal.Toolbar.Visible = 'off';
%                    app.SignalFiltered.Toolbar.Visible  = 'off';
%                    app.SignalFft.Toolbar.Visible  = 'off';
%                    app.SignalFftFiltered.Toolbar.Visible  = 'off';
%                    
%                    app.ExportText.Visible = 'on';
%                    app.OutpuFormat.Visible = 'on';
%                    app.ExportarButton.Visible = 'on';
%                    app.OutputNameField.Visible = 'on';
%                    app.OutpuFormatText.Visible = 'on';
%                    app.OutputNameText.Visible = 'on';
%                    
%                    return;
%                    
% 
%                    
%                 case 'About'
%                    app.CreadoelEditField.Visible = 'off';
%                    app.CreadoelEditFieldLabel.Visible = 'off';
%                    app.DirectorioEditField.Visible = 'off';
%                    app.DirectorioEditFieldLabel.Visible = 'off';            
%                    app.NombreEditField.Visible = 'off';
%                    app.NombreEditFieldLabel.Visible = 'off';            
%                    app.fileStatus.Visible = 'off';          
%                    app.TamaoKBEditField.Visible = 'off';
%                    app.TamaoKBEditFieldLabel.Visible = 'off';        
%                    app.DuracionEditField.Visible = 'off';
%                    app.DuracionEditFieldLabel.Visible = 'off';           
%                    app.SelecioneunamuestraButton.Visible = 'off';
%                    app.FrecuenciaEditField.Visible = 'off';
%                    app.FrecuenciaEditFieldLabel.Visible = 'off';
%                    
%                    app.Signal.Visible = 'off';
%                    app.SignalFiltered.Visible = 'off';
%                    app.SignalFft.Visible = 'off';
%                    app.SignalFftFiltered.Visible = 'off';
%                    app.GraficarButton.Visible = 'off';
%                    app.Grid.Visible = 'off';
%                    app.Signal.Toolbar.Visible = 'off';
%                    app.SignalFiltered.Toolbar.Visible  = 'off';
%                    app.SignalFft.Toolbar.Visible  = 'off';
%                    app.SignalFftFiltered.Toolbar.Visible  = 'off';
%                    
%                    app.ExportText.Visible = 'off';
%                    app.OutpuFormat.Visible = 'off';
%                    app.ExportarButton.Visible = 'off';
%                    app.OutputNameField.Visible = 'off';
%                    app.OutpuFormatText.Visible = 'off';
%                    app.OutputNameText.Visible = 'off';
%                    
%                    return;
%                     
%             end


            % --------------- Codigo optimizado ------------ %


            value = app.ListBox.Value;

            % Limpiar gráficos
            cla(app.Signal);
            cla(app.SignalFiltered);
            cla(app.SignalFft);
            cla(app.SignalFftFiltered);

            % Crear matrices de handles para los componentes
            editFields = [app.CreadoelEditField, app.DirectorioEditField, app.NombreEditField, app.fileStatus, ...
                            app.TamaoKBEditField, app.DuracionEditField, app.SelecioneunamuestraButton, app.FrecuenciaEditField];
            editFieldLabels = [app.CreadoelEditFieldLabel, app.DirectorioEditFieldLabel, app.NombreEditFieldLabel, ...
                            app.TamaoKBEditFieldLabel, app.DuracionEditFieldLabel, app.FrecuenciaEditFieldLabel];
            signals = [app.Signal, app.SignalFiltered, app.SignalFft, app.SignalFftFiltered];
            toolbarHandles = [app.Signal.Toolbar, app.SignalFiltered.Toolbar, app.SignalFft.Toolbar, app.SignalFftFiltered.Toolbar];
            exportComponents = [app.ExportText, app.OutpuFormat, app.ExportarButton, app.OutputNameField, ...
                            app.OutpuFormatText, app.OutputNameText, app.DestinationDir, app.DirectorioFoder];

            % Función auxiliar para cambiar la visibilidad
            setVisibility = @(components, visibility) set(components, 'Visible', visibility);

            switch value
                case 'Entradas'
                    % Mostrar EditFields y Labels asociados
                    setVisibility([editFields, editFieldLabels], 'on');
        
                    % Ocultar gráficos y otros componentes
                    setVisibility(signals, 'off');
                    setVisibility(toolbarHandles, 'off');
                    setVisibility(exportComponents, 'off');
                    app.GraficarButton.Visible = 'off';
                    app.Grid.Visible = 'off';

                case 'Visualizar'
                    % Ocultar EditFields y Labels asociados
                    setVisibility([editFields, editFieldLabels], 'off');
        
                    % Mostrar gráficos y otros componentes
                    setVisibility(signals, 'on');
                    setVisibility(toolbarHandles, 'on');
                    app.GraficarButton.Visible = 'on';
                    app.Grid.Visible = 'on';
        
                    % Ocultar export components
                    setVisibility(exportComponents, 'off');

                case 'Exportar'
                    % Ocultar EditFields y Labels asociados
                    setVisibility([editFields, editFieldLabels], 'off');
        
                    % Ocultar gráficos y otros componentes
                    setVisibility(signals, 'off');
                    setVisibility(toolbarHandles, 'off');
                    app.GraficarButton.Visible = 'off';
                    app.Grid.Visible = 'off';
        
                    % Mostrar export components
                    setVisibility(exportComponents, 'on');

                case 'About'
                    % Ocultar EditFields y Labels asociados
                    setVisibility([editFields, editFieldLabels], 'off');
        
                    % Ocultar gráficos y otros componentes
                    setVisibility(signals, 'off');
                    setVisibility(toolbarHandles, 'off');
                    app.GraficarButton.Visible = 'off';
                    app.Grid.Visible = 'off';
        
                    % Ocultar export components
                    setVisibility(exportComponents, 'off');
            end

            
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            global file path
            
            if isempty(file) || isempty(path)
                % Hacerle saber al usuario que ingrese primero un archivo a las entrada %
                return;
            end
            
            % Validar si no se ingreso ningun archivo %
            [val, ecg_final3, t, f, F, ecg_cleanf] = loadMatFile(fullfile(path, file));
            
            plot(app.Signal, val);
            plot(app.SignalFiltered, t, ecg_final3);
            plot(app.SignalFft, f, F);
            plot(app.SignalFftFiltered, f, ecg_cleanf);
            return;
        end

        % Value changed function: Grid
        function GridValueChanged(app, event)
            value = app.Grid.Value;
            
            if value
                grid(app.Signal, 'on');
                grid(app.SignalFiltered, 'on');
                grid(app.SignalFft, 'on');
                grid(app.SignalFftFiltered, 'on');
                
                return;
            else 
                grid(app.Signal, 'off');
                grid(app.SignalFiltered, 'off');
                grid(app.SignalFft, 'off');
                grid(app.SignalFftFiltered, 'off');
                
                return;
            end
        end

        % Button pushed function: ExportarButton
        function ExportarButtonPushed(app, event)
            global path file
            
            format = app.OutpuFormat.Value;
            
            switch format
                
                case 'Excel'
                    % LLamar a la funcion para importar los datos a excel %
                    matToExcel(fullfile(path, file), app.DirectorioFoder.Value, app.OutputNameField.Value);
                    
                    return;
                    
                case 'CSV'
                    % llamar a la funcion para importar datos a CSV %
                    matToCsv(fullfile(path, file), app.DirectorioFoder.Value, app.OutputNameField.Value);
                    
                    return;
                case 'HDF5'
                    exportECGToHDF5(fullfile(path, file), app.DirectorioFoder.Value, app.OutputNameField.Value);
                    
                    return;
                case 'EDF'
                    %exportToECGToEDF_v2()
                    
                    return;
            end
            
            
        end

        % Button pushed function: DestinationDir
        function DestinationDirButtonPushed(app, event)
            folder = uigetdir;
            if isequal(folder, 0)
                
                return;
            else
                app.DirectorioFoder.Value =  folder;               
                return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Signal = uiaxes(app.UIFigure);
            title(app.Signal, 'Señal No Filtrada')
            xlabel(app.Signal, 't')
            app.Signal.Visible = 'off';
            app.SignalFiltered = uiaxes(app.UIFigure);
            title(app.SignalFiltered, 'Señal Filtrada')
            xlabel(app.SignalFiltered, 't')
            app.SignalFiltered.Visible = 'off';
            app.SignalFft = uiaxes(app.UIFigure);
            title(app.SignalFft, 'Señal En Frecuencia ')
            xlabel(app.SignalFft, 'Hz')
            app.SignalFft.Visible = 'off';
            app.SignalFftFiltered = uiaxes(app.UIFigure);
            title(app.SignalFftFiltered, 'Señal en F recuencia Filtrada')
            xlabel(app.SignalFftFiltered, 'Hz')
            app.SignalFftFiltered.Visible = 'off';
            app.fileStatus = uilabel(app.UIFigure);
            app.fileStatus.Text = 'Seleccione una muestra .mat';
            app.SelecioneunamuestraButton = uibutton(app.UIFigure, 'push');
            app.SelecioneunamuestraButton.ButtonPushedFcn = createCallbackFcn(app, @SelecioneunamuestraButtonPushed, true);
            app.SelecioneunamuestraButton.Text = {'Selecione una muestra'; ''};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Entradas', 'Visualizar', 'Exportar', 'About '};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Entradas';
            app.NombreEditFieldLabel = uilabel(app.UIFigure);
            app.NombreEditFieldLabel.Text = {'Nombre: '; ''};
            app.NombreEditField = uieditfield(app.UIFigure, 'text');
            app.NombreEditField.Editable = 'off';
            app.TamaoKBEditFieldLabel = uilabel(app.UIFigure);
            app.TamaoKBEditFieldLabel.Text = {'Tamaño (KB):'; ''};
            app.TamaoKBEditField = uieditfield(app.UIFigure, 'text');
            app.TamaoKBEditField.Editable = 'off';
            app.CreadoelEditFieldLabel = uilabel(app.UIFigure);
            app.CreadoelEditFieldLabel.Text = {'Creado el:'; ''};
            app.CreadoelEditField = uieditfield(app.UIFigure, 'text');
            app.CreadoelEditField.Editable = 'off';
            app.DuracionEditFieldLabel = uilabel(app.UIFigure);
            app.DuracionEditFieldLabel.Text = 'Duracion:';
            app.DuracionEditField = uieditfield(app.UIFigure, 'text');
            app.DuracionEditField.Editable = 'off';
            app.DirectorioEditFieldLabel = uilabel(app.UIFigure);
            app.DirectorioEditFieldLabel.Text = 'Directorio:';
            app.DirectorioEditField = uieditfield(app.UIFigure, 'text');
            app.DirectorioEditField.Editable = 'off';
            app.Grid = uicheckbox(app.UIFigure);
            app.Grid.ValueChangedFcn = createCallbackFcn(app, @GridValueChanged, true);
            app.Grid.Visible = 'off';
            app.Grid.Text = 'Rejilla';
            app.CheckBox2 = uicheckbox(app.UIFigure);
            app.CheckBox2.Text = 'Check Box2';
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Visible = 'off';
            app.GraficarButton.Text = 'Graficar';
            app.FrecuenciaEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia';
            app.FrecuenciaEditField = uieditfield(app.UIFigure, 'text');
            app.FrecuenciaEditField.Editable = 'off';
            app.ExportarButton = uibutton(app.UIFigure, 'push');
            app.ExportarButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarButtonPushed, true);
            app.ExportarButton.Visible = 'off';
            app.ExportarButton.Text = 'Exportar';
            app.OutpuFormat = uidropdown(app.UIFigure);
            app.OutpuFormat.Items = {'Excel', 'CSV', 'HDF5', 'EDF'};
            app.OutpuFormat.Visible = 'off';
            app.OutpuFormat.Value = 'Excel';
            app.ExportText = uilabel(app.UIFigure);
            app.ExportText.Visible = 'off';
            app.ExportText.Text = {'Exporte la Señal Filtrada'; ''};
            app.OutputNameField = uieditfield(app.UIFigure, 'text');
            app.OutputNameField.Visible = 'off';
            app.OutputNameText = uilabel(app.UIFigure);
            app.OutputNameText.Visible = 'off';
            app.OutputNameText.Text = {'Nombre del archivo Nuevo'; ''; ''};
            app.OutpuFormatText = uilabel(app.UIFigure);
            app.OutpuFormatText.Visible = 'off';
            app.OutpuFormatText.Text = 'Formato del archivo de salida';
            app.DestinationDir = uibutton(app.UIFigure, 'push');
            app.DestinationDir.ButtonPushedFcn = createCallbackFcn(app, @DestinationDirButtonPushed, true);
            app.DestinationDir.Visible = 'off';
            app.DestinationDir.Text = {'Seleccione una carpeta para guardar el archivo'; ''};
            app.DirectorioFoder = uieditfield(app.UIFigure, 'text');
            app.DirectorioFoder.Editable = 'off';
            app.DirectorioFoder.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SyS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end